classdef PRMIA_RM_Challenge_Dashboard_CPDOCC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OptionsListBoxLabel          matlab.ui.control.Label
        OptionsListBox               matlab.ui.control.ListBox
        PortfolioOverviewLabel       matlab.ui.control.Label
        RiskAssessmentLabel          matlab.ui.control.Label
        IncidentReportLabel          matlab.ui.control.Label
        PerformanceLabel             matlab.ui.control.Label
        SimulationTypeDropDownLabel  matlab.ui.control.Label
        SimType                      matlab.ui.control.DropDown
        AlphaLevelLabel              matlab.ui.control.Label
        Alpha                        matlab.ui.control.DropDown
        DetailedTable                matlab.ui.control.Table
        OpenActionsTable             matlab.ui.control.Table
        OpenActionsLabel             matlab.ui.control.Label
        TotalMarketValueLabel        matlab.ui.control.Label
        TMV                          matlab.ui.control.EditField
        YoYReturnLabel               matlab.ui.control.Label
        TR                           matlab.ui.control.EditField
        SharpeRatioLabel             matlab.ui.control.Label
        SR                           matlab.ui.control.EditField
        GenerateReportButton         matlab.ui.control.Button
        DispDist                     matlab.ui.control.UIAxes
        Incidence                    matlab.ui.control.UIAxes
        PerformancePie               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        
        data=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\FXOpt_Data.csv'));
        OpenActions=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\Other Stuff\OpenActions.csv'));
        
        IncidenceData=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\Other Stuff\Incidence.csv'));
        
        AUDdetailed=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\Other Stuff\Detailed Sets\AUDdetailed.csv'));
        EURdetailed=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\Other Stuff\Detailed Sets\EURdetailed.csv'));
        JPYdetailed=readtable(('C:\\Users\Conner\Desktop\FS Coursework\PRMIA Competition\1_Data\Other Stuff\Detailed Sets\JPYdetailed.csv'));
        
        Sharpe;
        YoYRet;
        
        losses;
        window;
        SimLosses;
        sigma;
        
        report;
        imgStyle;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import mlreportgen.report.*
            import mlreportgen.dom.*
            
            %Setting up VaR Default Case
            alpha_val=str2double(app.Alpha.Value);
            fig=app.DispDist;
            nbins=30;
            app.losses=rmmissing(-app.data.DailyChange);
            
            app.window=length(app.losses)-252:length(app.losses);
            app.losses=app.losses(app.window);
            
            HistVaR=quantile(app.losses,1-alpha_val);
            ES=mean(app.losses(app.losses>=HistVaR));
            
            histogram(fig,app.losses,nbins);
            VaRLine=xline(fig,HistVaR,'-');
            VaRLine.Label=sprintf('Historical %dVaR = %.5f',(1-alpha_val)*100,round(HistVaR, 5));
            ESLine=xline(fig,ES,'-');
            ESLine.Label=sprintf('Historical ES = %.5f',round(ES,5));
            
            %Setting up open actions
            
            app.OpenActions.Properties.VariableNames{1} = 'Risk Name';
            app.OpenActions.Properties.VariableNames{2} = 'Exposure (in AUD)';
            app.OpenActions.Properties.VariableNames{3} = 'Inherent Risk';
            
            app.OpenActionsTable.ColumnName=app.OpenActions.Properties.VariableNames;
            app.OpenActionsTable.Data=app.OpenActions;
            
            high=uistyle('BackgroundColor','red');
            med=uistyle('BackgroundColor','yellow');
            low=uistyle('BackgroundColor','green');
            
            set(app.OpenActionsTable,'ColumnWidth',{300 85 93});
            
            highRiskIndices=find(strcmp(app.OpenActionsTable.Data.('Inherent Risk'),'High')==1);
            highRisks=[];
            
            for i=1:length(highRiskIndices)
                index=[highRiskIndices(i) 3];
                if i==0
                    highRisks=index;
                else
                    highRisks=vertcat(highRisks, index);
                end
            end
            addStyle(app.OpenActionsTable, high,'cell',highRisks);
            
            medRiskIndices=find(strcmp(app.OpenActionsTable.Data.('Inherent Risk'),'Moderate')==1);
            medRisks=[];
            
            for i=1:length(medRiskIndices)
                index=[medRiskIndices(i) 3];
                if i==0
                    medRisks=index;
                else
                    medRisks=vertcat(medRisks, index);
                end
            end
            addStyle(app.OpenActionsTable, med,'cell',medRisks);
            
            lowRiskIndices=find(strcmp(app.OpenActionsTable.Data.('Inherent Risk'),'Low')==1);
            lowRisks=[];
            
            for i=1:length(lowRiskIndices)
                index=[lowRiskIndices(i) 3];
                if i==0
                    lowRisks=index;
                else
                    lowRisks=vertcat(lowRisks, index);
                end
            end
            addStyle(app.OpenActionsTable, low,'cell',lowRisks);
            
            %Setting up incident report
            
            IncidentType=['1-Day VaR Violations' 'Warnings' 'Corrections' 'Exception Approvals'];
            app.IncidenceData.Properties.DimensionNames{1}=IncidentType;
            X=categorical({'1-Day VaR Violations' 'Corrections' 'Exception Approvals' 'Warnings'});
            
            yyaxis(app.Incidence,'left');
            b=bar(app.Incidence,X,app.IncidenceData.Total);
            xtips=b(1).XEndPoints;
            ytips=b(1).YEndPoints;
            labels=string(b(1).YData);
            text(app.Incidence,xtips,ytips,labels,'HorizontalAlignment','center','VerticalAlignment','bottom');
            
            yyaxis(app.Incidence,'right');
            plot(app.Incidence,X,app.IncidenceData.Today,'r',X,app.IncidenceData.MonthlyAverage,'g');
            ylim(app.Incidence,[0 300]);
            TodayLabels=string(app.IncidenceData.Today);
            AvLabels=string(app.IncidenceData.MonthlyAverage);
            text(app.Incidence,X,app.IncidenceData.Today+42,TodayLabels,'VerticalAlignment','top','HorizontalAlignment','center');
            text(app.Incidence,X,app.IncidenceData.MonthlyAverage+45,AvLabels,'VerticalAlignment','top','HorizontalAlignment','center');
            
            LegLabels={'Yearly Total','Today','Monthly Average'};
            legend(app.Incidence,LegLabels, 'Position', [0.88 0.5 0.1 0.01]);
            
            
            %Setting up detailed table
            
            app.AUDdetailed.Properties.VariableNames{2} = 'Call/Put';
            app.AUDdetailed.Properties.VariableNames{5} = 'Last Price';
            app.AUDdetailed.Properties.VariableNames{6} = 'Position Value';
            app.AUDdetailed.Properties.VariableNames{7} = 'Implied Volatility';
            
            app.DetailedTable.ColumnName=app.AUDdetailed.Properties.VariableNames;
            app.DetailedTable.Data=app.AUDdetailed;
            
            app.JPYdetailed.Properties.VariableNames{2} = 'Call/Put';
            app.JPYdetailed.Properties.VariableNames{5} = 'Last Price';
            app.JPYdetailed.Properties.VariableNames{6} = 'Position Value';
            app.JPYdetailed.Properties.VariableNames{7} = 'Implied Volatility';
            
            app.EURdetailed.Properties.VariableNames{2} = 'Call/Put';
            app.EURdetailed.Properties.VariableNames{5} = 'Last Price';
            app.EURdetailed.Properties.VariableNames{6} = 'Position Value';
            app.EURdetailed.Properties.VariableNames{7} = 'Implied Volatility';
            
            %Setting up performance pie
            
            AUDtotal=sum(app.AUDdetailed.('Position Value'));
            EURtotal=sum(app.EURdetailed.('Position Value'));
            JPYtotal=sum(app.JPYdetailed.('Position Value'));
            
            PortTotal=[AUDtotal; EURtotal; JPYtotal];
            app.TMV.Value=sprintf('$%.2f',sum(PortTotal));
            
            app.TR.Value='-4.05%';
            app.SR.Value='-0.73';
            
            labels={'AUD/USD','EUR/USD','JPY/USD'};
            pie(app.PerformancePie,PortTotal);
            legend(app.PerformancePie,labels,'Position',[0.007 0.85 0.1 0.01]);
            
            %Monte Carlo Sim
        
            period=app.data(app.window,:);
            
            w=[7 -4 2];
            
            AUDdat=rmmissing(-period.AUDDC);
            EURdat=rmmissing(-period.EURDC);
            JPYdat=rmmissing(-period.JPYDC);
            
            cdfAUD=ksdensity(AUDdat,AUDdat,'function','cdf');
            cdfEUR=ksdensity(EURdat,EURdat,'function','cdf');
            cdfJPY=ksdensity(JPYdat,JPYdat,'function','cdf');
            
            [Rho, nu] = copulafit('t',[cdfAUD cdfEUR cdfJPY],'Method','ApproximateML');
            
            r = copularnd('t',Rho, nu,10000);
            
            AUDsim=r(:,1);
            EURsim=r(:,2);
            JPYsim=r(:,3);
            
            AUDsim=ksdensity(AUDdat,AUDsim,'function','icdf');
            EURsim=ksdensity(EURdat,EURsim,'function','icdf');
            JPYsim=ksdensity(JPYdat,JPYsim,'function','icdf');
            
            SimData=table(AUDsim,EURsim,JPYsim);
            SimData=table2array(SimData);
            
            app.SimLosses=SimData*transpose(w);
            
            %EWMA Sim
        
            Lambda=.94;
            Sigma2     = zeros(length(app.losses),1);
            Sigma2(1)  = app.losses(1)^2;
            
            for i = 2 : length(app.losses)
                Sigma2(i) = (1-Lambda) * app.losses(i-1)^2 + Lambda * Sigma2(i-1);
            end
            
            app.sigma=sqrt((1-Lambda)*app.losses(end-1)^2 + Lambda*Sigma2(end));
            
            %Initializing Report and Related vars
            
            app.report=Report('Risk Report','pdf');
            app.imgStyle = {ScaleToFit(true)};
        end

        % Value changed function: SimType
        function SimTypeValueChanged(app, event)
            import mlreportgen.report.*
            import mlreportgen.dom.*
            
            value = app.SimType.Value;
            alpha_val=str2double(app.Alpha.Value);
            fig=app.DispDist;
            nbins=30;
            
            if strcmp(value,'Historical')==1
                
                HistVaR=quantile(app.losses,1-alpha_val);
                ES=mean(app.losses(app.losses>=HistVaR));
                
                histogram(fig,app.losses,nbins);
                VaRLine=xline(fig,HistVaR,'-');
                VaRLine.Label=sprintf('Historical %dVaR = %.5f',(1-alpha_val)*100,round(HistVaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('Historical ES = %.5f',round(ES,5));
                
            elseif strcmp(value,'Monte Carlo')==1
                
                SimVaR=quantile(app.SimLosses,1-alpha_val);
                ES=mean(app.SimLosses(app.SimLosses>=SimVaR));
                
                histogram(fig,app.SimLosses,nbins);
                VaRLine=xline(fig,SimVaR,'-');
                VaRLine.Label=sprintf('Simulated %dVaR = %.5f',(1-alpha_val)*100,round(SimVaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('Simulated ES = %.5f',round(ES,5));
                
                
            elseif strcmp(value,'Exponential Weighted Moving Average')==1
                
                Zscore=norminv(1-alpha_val);
                EWMA_VaR=Zscore*app.sigma;
                ES=mean(app.losses(app.losses>EWMA_VaR));
                
                histogram(fig,app.losses,nbins);
                VaRLine=xline(fig,EWMA_VaR,'-');
                VaRLine.Label=sprintf('EWMA %dVaR = %.5f',(1-alpha_val)*100,round(EWMA_VaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('EWMA ES = %.5f',round(ES,5));
                
            end
            
        end

        % Value changed function: Alpha
        function AlphaValueChanged(app, event)
            value = app.SimType.Value;
            alpha_val=str2double(app.Alpha.Value);
            fig=app.DispDist;
            nbins=30;
            
            if strcmp(value,'Historical')==1
                HistVaR=quantile(app.losses,1-alpha_val);
                ES=mean(app.losses(app.losses>=HistVaR));
                
                histogram(fig,app.losses,nbins);
                VaRLine=xline(fig,HistVaR,'-');
                VaRLine.Label=sprintf('Historical %dVaR = %.5f',(1-alpha_val)*100,round(HistVaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('Historical ES = %.5f',round(ES,5));
                
            elseif strcmp(value,'Monte Carlo')==1
                
                SimVaR=quantile(app.SimLosses,1-alpha_val);
                ES=mean(app.SimLosses(app.SimLosses>=SimVaR));
                
                histogram(fig,app.SimLosses,nbins);
                VaRLine=xline(fig,SimVaR,'-');
                VaRLine.Label=sprintf('Simulated %dVaR = %.5f',(1-alpha_val)*100,round(SimVaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('Simulated ES = %.5f',round(ES,5));

            elseif strcmp(value,'Exponential Weighted Moving Average')==1
                
                Zscore=norminv(1-alpha_val);
                EWMA_VaR=Zscore*app.sigma;
                
                ES=mean(app.losses(app.losses>EWMA_VaR));
                
                histogram(fig,app.losses,nbins);
                VaRLine=xline(fig,EWMA_VaR,'-');
                VaRLine.Label=sprintf('EWMA %dVaR = %.5f',(1-alpha_val)*100,round(EWMA_VaR, 5));
                ESLine=xline(fig,ES,'-');
                ESLine.Label=sprintf('EWMA ES = %.5f',round(ES,5));
            end
        end

        % Value changed function: OptionsListBox
        function OptionsListBoxValueChanged(app, event)

            value = app.OptionsListBox.Value;
            
            if strcmp(value,'AUD/USD')==1
                app.DetailedTable.ColumnName=app.AUDdetailed.Properties.VariableNames;
                app.DetailedTable.Data=app.AUDdetailed;
                
            elseif strcmp(value,'EUR/USD')==1
                app.DetailedTable.ColumnName=app.EURdetailed.Properties.VariableNames;
                app.DetailedTable.Data=app.EURdetailed;
                
            elseif strcmp(value,'JPY/USD')==1
                app.DetailedTable.ColumnName=app.JPYdetailed.Properties.VariableNames;
                app.DetailedTable.Data=app.JPYdetailed;
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            import mlreportgen.report.*
            import mlreportgen.dom.*
            
            space=Table(' ');
            space.entry(1,1).Style = {Width('3in'), Height('1in')};
            alpha_val=str2double(app.Alpha.Value);
            nbins=30;
            
            titlepg = TitlePage;
            titlepg.Title = 'Risk Report';
            titlepg.Author='Dashboard User';
            add(app.report,titlepg);
            
            tableStyle = { ...
                Width('100%'), ...
                Border('solid','black','1px'), ...
                ColSep('solid','black','1px'), ...
                RowSep('solid','black','1px') ...
                };
            
            
            %VaR Report
            VaRch=Chapter('Value at Risk Assessment');
            
            %Hist
            HistVaR=quantile(app.losses,1-alpha_val);
            ES=mean(app.losses(app.losses>=HistVaR));
            
            histf=figure('visible','off');
            histogram(app.losses,nbins);
            VaRLine=xline(HistVaR,'-');
            VaRLine.Label=sprintf('Historical %dVaR = %.5f',(1-alpha_val)*100,round(HistVaR, 5));
            ESLine=xline(ES,'-');
            ESLine.Label=sprintf('Historical ES = %.5f',round(ES,5));
            title(sprintf('Historical %dVaR and ES (In Millions AUD)',(1-alpha_val)*100));
            
            HistFig=Figure(histf);
            HistImage=Image(getSnapshotImage(HistFig,app.report));
            HistImage.Style=app.imgStyle;
            delete(gcf);
            
            %MC
            SimVaR=quantile(app.SimLosses,1-alpha_val);
            ES=mean(app.SimLosses(app.SimLosses>=SimVaR));
            
            simf=figure('visible','off');
            histogram(app.SimLosses,nbins);
            VaRLine=xline(SimVaR,'-');
            VaRLine.Label=sprintf('Simulated %dVaR = %.5f',(1-alpha_val)*100,round(SimVaR, 5));
            ESLine=xline(ES,'-');
            ESLine.Label=sprintf('Simulated ES = %.5f',round(ES,5));
            title(sprintf('Simulated %dVaR and ES (In Millions AUD)', (1-alpha_val)*100));
            
            SimFig=Figure(simf);
            SimImage=Image(getSnapshotImage(SimFig,app.report));
            SimImage.Style=app.imgStyle;
            delete(gcf);
            
            %EWMA
            Zscore=norminv(1-alpha_val);
            EWMA_VaR=Zscore*app.sigma;
            
            ES=mean(app.losses(app.losses>EWMA_VaR));
            
            ewmaf=figure('visible','off');
            histogram(app.losses,nbins);
            VaRLine=xline(EWMA_VaR,'-');
            VaRLine.Label=sprintf('EWMA %dVaR = %.5f',(1-alpha_val)*100,round(EWMA_VaR, 5));
            ESLine=xline(ES,'-');
            ESLine.Label=sprintf('EWMA ES = %.5f',round(ES,5));
            title(sprintf('EWMA %dVaR and ES (In Millions AUD)',(1-alpha_val)*100));
            
            EWMAFig=Figure(ewmaf);
            EWMAImage=Image(getSnapshotImage(EWMAFig,app.report));
            EWMAImage.Style=app.imgStyle;
            delete(gcf);

            add(VaRch,HistImage);
            add(VaRch,SimImage);
            add(VaRch,EWMAImage);
            add(app.report,VaRch);
            
            %Operational Risk Assessment
            
            ORAch=Chapter('Operational Risk Assessment');
            
            X=categorical({'1-Day VaR Violations' 'Corrections' 'Exception Approvals' 'Warnings'});
            
            oraf=figure('visible','off');
            yyaxis('left');
            b=bar(X,app.IncidenceData.Total);
            xtips=b(1).XEndPoints;
            ytips=b(1).YEndPoints;
            labels=string(b(1).YData);
            text(xtips,ytips,labels,'HorizontalAlignment','center','VerticalAlignment','bottom');
            
            yyaxis('right');
            plot(X,app.IncidenceData.Today,'r',X,app.IncidenceData.MonthlyAverage,'g');
            ylim([0 300]);
            TodayLabels=string(app.IncidenceData.Today);
            AvLabels=string(app.IncidenceData.MonthlyAverage);
            text(X,app.IncidenceData.Today+42,TodayLabels,'VerticalAlignment','top','HorizontalAlignment','center');
            text(X,app.IncidenceData.MonthlyAverage+45,AvLabels,'VerticalAlignment','top','HorizontalAlignment','center');
            
            LegLabels={'Yearly Total','Today','Monthly Average'};
            legend(LegLabels, 'Position', [0.7 0.8 0.1 0.01]);
            title('Incident Report');
            
            ORAfig=Figure(oraf);
            ORAImage=Image(getSnapshotImage(ORAfig,app.report));
            ORAImage.Style=app.imgStyle;
            delete(gcf);
            
            space=Table(' ');
            space.entry(1,1).Style = {Width('3in'), Height('1in')};           

            OATable=Table(app.OpenActionsTable.Data);
            OATable.Style=tableStyle;
            
            add(ORAch,ORAImage);
            add(ORAch, space);
            add(ORAch,OATable);
            add(app.report,ORAch);
            
            %Performance and Portfolio Overview
            
            Perfch=Chapter('Performance and Portfolio Overview');
            
            AUDtotal=sum(app.AUDdetailed.('Position Value'));
            EURtotal=sum(app.EURdetailed.('Position Value'));
            JPYtotal=sum(app.JPYdetailed.('Position Value'));
            
            PortTotal=[AUDtotal; EURtotal; JPYtotal];

            pief=figure('visible','off');
            labels={'AUD/USD','EUR/USD','JPY/USD'};
            pie(PortTotal);
            legend(labels,'Position',[0.05 0.85 0.1 0.01]);
            title('Market Value');
            
            PieFig=Figure(pief);
            PieImage=Image(getSnapshotImage(PieFig,app.report));
            PieImage.Style=app.imgStyle;
            delete(gcf);
            
            SR=['Sharpe Ratio: ',app.SR.Value];
            YoYR=['YoY Return: ',app.TR.Value];
            TMV=['Total Market Value: ',app.TMV.Value];
            
            perfTable=Table({SR,' ',YoYR,' ',TMV});
            perfTable.entry(1,2).Style = {Width('.4in'), Height('.2in')};
            perfTable.entry(1,4).Style={Width('.4in'), Height('.2in')};
            
            add(Perfch,PieImage);
            add(Perfch,perfTable);
            add(app.report,Perfch);
            
            close(app.report);
            
            rptview(app.report);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsListBoxLabel = uilabel(app.UIFigure);
            app.OptionsListBoxLabel.Text = 'Options';
            app.OptionsListBox = uilistbox(app.UIFigure);
            app.OptionsListBox.Items = {'AUD/USD', 'EUR/USD', 'JPY/USD'};
            app.OptionsListBox.ValueChangedFcn = createCallbackFcn(app, @OptionsListBoxValueChanged, true);
            app.OptionsListBox.Value = 'AUD/USD';
            app.PortfolioOverviewLabel = uilabel(app.UIFigure);
            app.PortfolioOverviewLabel.Text = 'Portfolio Overview';
            app.RiskAssessmentLabel = uilabel(app.UIFigure);
            app.RiskAssessmentLabel.Text = 'Risk Assessment';
            app.IncidentReportLabel = uilabel(app.UIFigure);
            app.IncidentReportLabel.Text = 'Incident Report';
            app.PerformanceLabel = uilabel(app.UIFigure);
            app.PerformanceLabel.Text = 'Performance';
            app.SimulationTypeDropDownLabel = uilabel(app.UIFigure);
            app.SimulationTypeDropDownLabel.Text = 'Simulation Type';
            app.SimType = uidropdown(app.UIFigure);
            app.SimType.Items = {'Historical', 'Monte Carlo', 'Exponential Weighted Moving Average'};
            app.SimType.ValueChangedFcn = createCallbackFcn(app, @SimTypeValueChanged, true);
            app.SimType.Value = 'Historical';
            app.AlphaLevelLabel = uilabel(app.UIFigure);
            app.AlphaLevelLabel.Text = 'Alpha Level';
            app.Alpha = uidropdown(app.UIFigure);
            app.Alpha.Items = {'0.1', '0.05', '0.01'};
            app.Alpha.ValueChangedFcn = createCallbackFcn(app, @AlphaValueChanged, true);
            app.Alpha.Value = '0.05';
            app.DetailedTable = uitable(app.UIFigure);
            app.DetailedTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'};
            app.DetailedTable.RowName = {};
            app.OpenActionsTable = uitable(app.UIFigure);
            app.OpenActionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OpenActionsTable.RowName = {};
            app.OpenActionsLabel = uilabel(app.UIFigure);
            app.OpenActionsLabel.Text = 'Open Actions';
            app.TotalMarketValueLabel = uilabel(app.UIFigure);
            app.TotalMarketValueLabel.Text = 'Total Market Value';
            app.TMV = uieditfield(app.UIFigure, 'text');
            app.YoYReturnLabel = uilabel(app.UIFigure);
            app.YoYReturnLabel.Text = 'YoY Return';
            app.TR = uieditfield(app.UIFigure, 'text');
            app.SharpeRatioLabel = uilabel(app.UIFigure);
            app.SharpeRatioLabel.Text = 'Sharpe Ratio';
            app.SR = uieditfield(app.UIFigure, 'text');
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.DispDist = uiaxes(app.UIFigure);
            title(app.DispDist, 'Loss Distribution (In Millions AUD)')
            xlabel(app.DispDist, 'Losses')
            zlabel(app.DispDist, 'Z')
            app.Incidence = uiaxes(app.UIFigure);
            title(app.Incidence, 'Incidents')
            xlabel(app.Incidence, 'Incident Type')
            ylabel(app.Incidence, 'Frequency')
            zlabel(app.Incidence, 'Z')
            app.PerformancePie = uiaxes(app.UIFigure);
            title(app.PerformancePie, 'Market Value')
            xlabel(app.PerformancePie, 'X')
            ylabel(app.PerformancePie, 'Y')
            zlabel(app.PerformancePie, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA_RM_Challenge_Dashboard_CPDOCC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end